classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindowUIFigure  matlab.ui.Figure
        EditField           matlab.ui.control.EditField
        Image               matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Doing_When_Open(app)
            global netTransfer;
            load netTransfer.mat;
            global index;
            index = 1;
            global Images_Dir;
            Images_Dir = dir('MP4\R\*.jpg');
        end

        % Key press function: MainWindowUIFigure
        function MainWindowUIFigureKeyPress(app, event)
            global Images_Dir;
            global index;
            global netTransfer;
            key = event.Key
            
            if key == "uparrow"
                if index < 300
                    index = index + 1
                end
            elseif key == "downarrow"
                if index > 1
                    index = index - 1
                end
            elseif key == "rightarrow"
                if Images_Dir(1).folder(end) == 'L'
                    Images_Dir = dir('MP4\R\*.jpg');
                elseif Images_Dir(1).folder(end) == 'R'
                    Images_Dir = dir('MP4\L\*.jpg');
                end
            else
            end
            
            File_Loacation_With_File_Name = [Images_Dir(index).folder,'\',Images_Dir(index).name];
            app.Image.ImageSource = File_Loacation_With_File_Name;
            Image_Gray = rgb2gray(imread(File_Loacation_With_File_Name));
            Image_Gray_Resized = imresize(Image_Gray,[120 160]);
            predictedLabel = classify(netTransfer,Image_Gray_Resized);
            app.EditField.Value = string(predictedLabel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindowUIFigure = uifigure('Visible', 'off');
            app.MainWindowUIFigure.Name = 'MainWindow';
            app.MainWindowUIFigure.KeyPressFcn = createCallbackFcn(app, @MainWindowUIFigureKeyPress, true);
            app.MainWindowUIFigure.Tag = 'MainWindow';
            app.EditField = uieditfield(app.MainWindowUIFigure, 'text');
            app.EditField.Tag = 'Image_Name';
            app.Image = uiimage(app.MainWindowUIFigure);
            app.Image.Tag = 'Image';
            % Show the figure after all components are created
            app.MainWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Doing_When_Open)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindowUIFigure)
        end
    end
end